classdef SD2_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        initialTrialsPanel              matlab.ui.container.Panel
        GenerateButton                  matlab.ui.control.Button
        iTrialLabel                     matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        nTrialsLabel                    matlab.ui.control.Label
        SamplingProcedure1constantstimuliPanel  matlab.ui.container.Panel
        nRepsEditFieldLabel             matlab.ui.control.Label
        SP1nReps                        matlab.ui.control.NumericEditField
        SDsLabel                        matlab.ui.control.Label
        SP1cohvector                    matlab.ui.control.EditField
        conddirLabel_3                  matlab.ui.control.Label
        TotaltrialsLabel_2              matlab.ui.control.Label
        SP1totaltrials                  matlab.ui.control.NumericEditField
        SP1invalidvals                  matlab.ui.control.Label
        SamplingProcedure2staircase1Panel  matlab.ui.container.Panel
        SP2invalidvals                  matlab.ui.control.Label
        SDsLabel_2                      matlab.ui.control.Label
        SP2cohvector                    matlab.ui.control.EditField
        UpEditField_2Label_2            matlab.ui.control.Label
        SP2up                           matlab.ui.control.NumericEditField
        DownEditField_2Label            matlab.ui.control.Label
        SP2down                         matlab.ui.control.NumericEditField
        nTrialsEditField_2Label         matlab.ui.control.Label
        SP2nTrials                      matlab.ui.control.NumericEditField
        startingvalueEditField_2Label   matlab.ui.control.Label
        SP2startval                     matlab.ui.control.NumericEditField
        conddirLabel                    matlab.ui.control.Label
        TotaltrialsLabel_3              matlab.ui.control.Label
        SP2totaltrials                  matlab.ui.control.NumericEditField
        SamplingProcedure3staircase2Panel  matlab.ui.container.Panel
        SP3invalidvals                  matlab.ui.control.Label
        SDsLabel_3                      matlab.ui.control.Label
        SP3cohvector                    matlab.ui.control.EditField
        UpEditField_4Label              matlab.ui.control.Label
        SP3up                           matlab.ui.control.NumericEditField
        DownEditField_3Label            matlab.ui.control.Label
        SP3down                         matlab.ui.control.NumericEditField
        nTrialsEditField_3Label         matlab.ui.control.Label
        SP3nTrials                      matlab.ui.control.NumericEditField
        startingvalueEditField_3Label   matlab.ui.control.Label
        SP3startval                     matlab.ui.control.NumericEditField
        conddirLabel_2                  matlab.ui.control.Label
        TotaltrialsLabel_4              matlab.ui.control.Label
        SP3totaltrials                  matlab.ui.control.NumericEditField
        stimAndResponsePanel            matlab.ui.container.Panel
        StimulusSpaceLabel              matlab.ui.control.Label
        SetButton                       matlab.ui.control.Button
        SpeedDiffVectorEditFieldLabel   matlab.ui.control.Label
        SpeedDiffVectorEditField        matlab.ui.control.EditField
        VelocitiesVectorEditFieldLabel  matlab.ui.control.Label
        VelocitiesVectorEditField       matlab.ui.control.EditField
        StateEditFieldLabel             matlab.ui.control.Label
        StateEditField                  matlab.ui.control.EditField
        ResponseSpaceEditFieldLabel     matlab.ui.control.Label
        ResponseSpaceEditField          matlab.ui.control.EditField
        ReadyButton                     matlab.ui.control.Button
        TCPpanel                        matlab.ui.container.Panel
        CloseButton                     matlab.ui.control.Button
        OpenButton                      matlab.ui.control.Button
        ConnectButton                   matlab.ui.control.Button
        DisconnectButton                matlab.ui.control.Button
        IPEditFieldLabel                matlab.ui.control.Label
        IPfield                         matlab.ui.control.EditField
        PortEditFieldLabel              matlab.ui.control.Label
        Portfield                       matlab.ui.control.NumericEditField
        connectFlagLabel                matlab.ui.control.Label
        openFlagLabel                   matlab.ui.control.Label
        TCPerrormsgLabel                matlab.ui.control.Label
        SaveLoadPanel                   matlab.ui.container.Panel
        SubjectnameEditFieldLabel       matlab.ui.control.Label
        SubjectnameEditField            matlab.ui.control.EditField
        SessionEditFieldLabel           matlab.ui.control.Label
        SessionEditField                matlab.ui.control.EditField
        SetDirButton                    matlab.ui.control.Button
        directoryName                   matlab.ui.control.EditField
        saveName                        matlab.ui.control.EditField
        SaveResultsButton               matlab.ui.control.Button
        AutosaveLabel                   matlab.ui.control.Label
        LoadParamsButton                matlab.ui.control.Button
        SaveParamsButton                matlab.ui.control.Button
        everyEditFieldLabel             matlab.ui.control.Label
        nAsave                          matlab.ui.control.NumericEditField
        SendPathButton                  matlab.ui.control.Button
        GlobalParametersPanel           matlab.ui.container.Panel
        respFeedbackBool                matlab.ui.control.CheckBox
        TrialDurationEditFieldLabel_2   matlab.ui.control.Label
        PauseeveryLabel                 matlab.ui.control.Label
        pausen                          matlab.ui.control.NumericEditField
        SendButton                      matlab.ui.control.Button
        NoDotsLabel                     matlab.ui.control.Label
        NoDotsEditField                 matlab.ui.control.NumericEditField
        DotSizeEditFieldLabel           matlab.ui.control.Label
        DotSizeEditField                matlab.ui.control.NumericEditField
        DotLifetimeEditFieldLabel       matlab.ui.control.Label
        DotLifetimeEditField            matlab.ui.control.NumericEditField
        TrialDurationEditFieldLabel     matlab.ui.control.Label
        TrialDurationEditField          matlab.ui.control.NumericEditField
        ISIEditFieldLabel               matlab.ui.control.Label
        ISIEditField                    matlab.ui.control.NumericEditField
        LoadSessionButton               matlab.ui.control.Button
        SpeedDifference2Label           matlab.ui.control.Label
    end

    
    properties (Access = public)
        
        t % tcpip socket
        path % directory dealing with saves/loads
        
        %% stim and response space stuff - coherences, velocities, walk state, response values
        coherences           % real coherence values
        velocities           % vector of velocities to be tested
        states                % stand or walk.
        responses  % vector, e.g. [0 1], [0 1 2 3]
        
        %% sampling parameters
        samplingParams; % struct with 3 fields (1 constant, 2 staircases),
        
        %% trials
        allTrials
        nTrials
        cond % struct which manages interleaved contitions, staircases etc.
        nConds % number of interleaved conditions
        nIni % number of initial trials
        iniTrials %
        iniflag % sampling method flag 1,2,3..
        
        nRepsAdaptive
        adaptTrials % preconfigured velocities/walk state combinations array for adapt trials interleaving
        totalTrials
        
        randsamplep % probability of picking a coherence at random during adaptive trials
        
        pars; % for saving pars
        loadResFlag = 0;
        
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startup(app)
            %initialTrialsMethodflag(app);
            
            app.DisconnectButton.Enable = 'off';
            app.OpenButton.Enable = 'off';
            app.CloseButton.Enable = 'off';
            app.connectFlagLabel.Text = 'Disconnected';
            app.openFlagLabel.Text = 'Closed';
            app.SendButton.Enable = 'off';
            app.randsamplep = 0;
            app.SP1invalidvals.Text = ' ';
            app.SP2invalidvals.Text = ' ';
            app.SP3invalidvals.Text = ' ';
            
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.t = tcpip(app.IPfield.Value, app.Portfield.Value, 'NetworkRole','Client','Timeout', inf);
            app.ConnectButton.Enable = 'off';
            app.OpenButton.Enable = 'on';
            app.CloseButton.Enable = 'off';
            app.DisconnectButton.Enable = 'on';
            app.connectFlagLabel.Text = 'Connected';
        end

        % Button pushed function: SendButton
        function setGlobalParams(app, event)
            numDots = app.NoDotsEditField.Value;
            dotsize = app.DotSizeEditField.Value;
            dotLifetime = app.DotLifetimeEditField.Value;
            trialDuration = app.TrialDurationEditField.Value;
            ISI = app.ISIEditField.Value;
            showFeedback = int8(app.respFeedbackBool.Value);
            pauseN = app.pausen.Value;
            toSend = char(['gp,' num2str(numDots), ',' num2str(trialDuration), ',' num2str(dotLifetime), ',' num2str(dotsize), ',' num2str(ISI), ',' num2str(showFeedback) ',' num2str(pauseN)]);
            fwrite(app.t, toSend);
        end

        % Button pushed function: CloseButton
        function closeTCP(app, event)
            try
                fclose(app.t);
            catch
            end
            app.CloseButton.Enable = 'off';
            app.OpenButton.Enable = 'on';
            app.openFlagLabel.Text = 'Closed';
            app.SendButton.Enable = 'off';
            app.SendPathButton.Enable = 'off';
        end

        % Button pushed function: OpenButton
        function openTCP(app, event)
            try
                fopen(app.t);
                app.OpenButton.Enable = 'off';
                app.CloseButton.Enable = 'on';
                app.openFlagLabel.Text = 'Open';
                app.SendButton.Enable = 'on';
                app.SendPathButton.Enable = 'on';
            catch
                app.TCPerrormsgLabel.Text ='Check Unity connection';
            end
        end

        % Button pushed function: SetButton
        function genStimSpace(app, event)
            % generate coherences vector (add corresponding negative values
            cohOrig = eval(app.SpeedDiffVectorEditField.Value);
            cohFlip = fliplr(cohOrig * -1);
            app.coherences = [cohFlip, cohOrig];
            % evaluate + assign velocities, states and responses
            app.velocities = eval(app.VelocitiesVectorEditField.Value);
            app.states = eval(app.StateEditField.Value);
            app.responses = eval(app.ResponseSpaceEditField.Value);
            
            set(app.GenerateButton, 'Enable', 'on')
        end

        % Button pushed function: GenerateButton
        function generateTrials(app, event)
            
            
            %% SP1: generate the constant sampling trials.
            % generate coherences with -ve values
            SP1cohvalstemp = eval(app.SP1cohvector.Value);
            SP1cohvals = [-1*fliplr(SP1cohvalstemp), SP1cohvalstemp]; % errors on 0
            SP1cohvals = sort(SP1cohvals);
            
            % check all valid coherences
            testValid = ismember(SP1cohvals, app.coherences);
            if ~all(testValid)
                app.SP1invalidvals.Text = 'invalid values';
            else
                app.SP1invalidvals.Text = ' ';
            end
            
            nreps = app.SP1nReps.Value;
            trialtag = 1;
            trialsubtag = 0;
            uniqueTrials = combvec(trialtag, trialsubtag, app.velocities, app.states, SP1cohvals);
            SP1allTrials = repmat(uniqueTrials, 1, nreps);
            
            app.SP1totaltrials.Value = size(SP1allTrials,2);
            
            %% SP2: staircase 1
            SP2cohvalstemp = eval(app.SP2cohvector.Value);
            SP2cohvals = [-1*fliplr(SP2cohvalstemp), SP2cohvalstemp]; % errors on 0
            SP2cohvals = sort(SP2cohvals);
            
            % check all valid coherences
            testValid = ismember(SP2cohvals, app.coherences);
            if ~all(testValid)
                app.SP2invalidvals.Text = 'invalid values';
            else
                app.SP2invalidvals.Text = ' ';
            end
            
            ntrials = app.SP2nTrials.Value;
            trialtag = 2; % SP2
            trialsubtag = [1 2]; % for forwards and backwards directions
            uniqueTrials = combvec(trialtag, trialsubtag, app.velocities, app.states, NaN);
            SP2allTrials = repmat(uniqueTrials, 1, ntrials);
            
            if ~ismember(app.SP2startval.Value, eval(app.SP2cohvector.Value))
                app.SP2invalidvals.Text = 'invalid start val';
            end
            
            app.SP2totaltrials.Value = size(SP2allTrials,2);
            
            %% SP3: staircase 2
            SP3cohvalstemp = eval(app.SP3cohvector.Value);
            SP3cohvals = [-1*fliplr(SP3cohvalstemp), SP3cohvalstemp]; % errors on 0
            SP3cohvals = sort(SP3cohvals);
            
            % check all valid coherences
            testValid = ismember(SP3cohvals, app.coherences);
            if ~all(testValid)
                app.SP3invalidvals.Text = 'invalid values';
            else
                app.SP3invalidvals.Text = ' ';
            end
            
            ntrials = app.SP3nTrials.Value;
            trialtag = 3; % SP3
            trialsubtag = [1 2]; % for forwards and backwards directions
            uniqueTrials = combvec(trialtag, trialsubtag, app.velocities, app.states, NaN);
            SP3allTrials = repmat(uniqueTrials, 1, ntrials);
            
            
            if ~ismember(app.SP3startval.Value, eval(app.SP3cohvector.Value))
                app.SP3invalidvals.Text = 'invalid start val';
            end
            
            app.SP3totaltrials.Value = size(SP3allTrials,2);
            
            app.nTrialsLabel.Text = string(app.SP1totaltrials.Value + app.SP2totaltrials.Value + app.SP3totaltrials.Value);
            
            %% create all the trials
            allthetrials = [SP1allTrials, SP2allTrials, SP3allTrials];
            allthetrials = [allthetrials; NaN*ones(1,size(allthetrials,2))];
            app.allTrials = allthetrials(:, randperm(size(allthetrials,2)));
            
            stillTrials = find(app.allTrials(4,:)==0);
            walkTrials = find(app.allTrials(4,:)==1);
            
            if ~isempty(walkTrials)
                blockedtrials = [];
                for iblock = 1:floor(numel([stillTrials, walkTrials])/2)
                    blockedtrials   = [blockedtrials   , app.allTrials(:,stillTrials(iblock)),...
                        app.allTrials(:,walkTrials(iblock))];
                end
                
                %randomise within blocks
                for iblock = 2:size(blockedtrials,2)/2
                    r = randi([0 1]);
                    temp = blockedtrials(:,iblock*2-1:iblock*2);
                    if r==1
                        temp = circshift(temp,1,2);
                    end
                    blockedtrials(:,iblock*2-1:iblock*2) = temp;
                end
                
                app.allTrials = blockedtrials;
                
            end
            
            app.nTrials = size(app.allTrials,2);


            %             for iblock = 1:numel(testVec)/4
            %                 r = randi([0 1]);
            %                 temp = testVec(iblock*4-3:iblock*4);
            %                 if r==1
            %                     temp = circshift(temp,2);
            %                 end
            %                 testVec(iblock*4-3:iblock*4) = temp;
            %             end


            %% create the staircase trackers
            for istate = 1:numel(app.states)
                for ivel = 1:numel(app.velocities)
                    for idir = 1:2
                        app.cond(ivel,istate).stair(1).dir(idir).coherences = sort(eval(app.SP2cohvector.Value));
                        app.cond(ivel,istate).stair(1).dir(idir).U = app.SP2up.Value;
                        app.cond(ivel,istate).stair(1).dir(idir).D = app.SP2down.Value;
                        app.cond(ivel,istate).stair(1).dir(idir).stimValIdx = find(app.cond(ivel,istate).stair(1).dir(idir).coherences==app.SP2startval.Value);
                        app.cond(ivel,istate).stair(1).dir(idir).startValIdx = app.cond(ivel,istate).stair(1).dir(idir).stimValIdx;
                        app.cond(ivel,istate).stair(1).dir(idir).resp = []; % no responses on initialisation...
                        app.cond(ivel,istate).stair(1).dir(idir).correctinarow = 0;
                        app.cond(ivel,istate).stair(1).dir(idir).incorrectinarow = 0;
                        app.cond(ivel,istate).stair(1).dir(idir).jumpVal = 1;
                        app.cond(ivel,istate).stair(1).dir(idir).nReversals = 0;
                        
                        app.cond(ivel,istate).stair(2).dir(idir).coherences = sort(eval(app.SP3cohvector.Value));
                        app.cond(ivel,istate).stair(2).dir(idir).U = app.SP3up.Value;
                        app.cond(ivel,istate).stair(2).dir(idir).D = app.SP3down.Value;
                        app.cond(ivel,istate).stair(2).dir(idir).stimValIdx = find(app.cond(ivel,istate).stair(2).dir(idir).coherences==app.SP3startval.Value);
                        app.cond(ivel,istate).stair(2).dir(idir).startValIdx = app.cond(ivel,istate).stair(2).dir(idir).stimValIdx;
                        app.cond(ivel,istate).stair(2).dir(idir).resp = []; % no responses on initialisation...
                        app.cond(ivel,istate).stair(2).dir(idir).correctinarow = 0;
                        app.cond(ivel,istate).stair(2).dir(idir).incorrectinarow = 0;
                        app.cond(ivel,istate).stair(2).dir(idir).jumpVal = 1;
                        app.cond(ivel,istate).stair(2).dir(idir).nReversals = 0;
                    end
                end
            end


            % total trials = nTrials * nConds * 2?


        end

        % Callback function
        function toggleLivePlotting(app, event)
            %  value = app.LiveplotCheckBox.Value;
            %  if value == 1
            %      for icon = 1:app.nConds
            %          ax = uiaxes(fig);
            %%subplot(ax, app.nConds,1,icon) -> not supported until 2018b! fuck sake!
            %%hold on, xlim([0 100]), ylim([-100 100]), grid on, box off, xlabel('Trial #'), ylabel('Coherence')
            %      end
            %  end
            %end
            
        end

        % Button pushed function: ReadyButton
        function startExperiment(app, event)
            
            params = struct;
            % Stimulus and response space
            params.cohVec = app.SpeedDiffVectorEditField.Value;
            params.velVec = app.VelocitiesVectorEditField.Value;
            params.state = app.StateEditField.Value;
            params.responseSpace = app.ResponseSpaceEditField.Value;
            
            % Trial settings
            params.SP1cohvector = app.SP1cohvector.Value;
            params.SP1nreps = app.SP1nReps.Value;
            
            params.SP2cohvector = app.SP2cohvector.Value;
            params.SP2up = app.SP2up.Value;
            params.SP2down = app.SP2down.Value;
            params.SP2startval = app.SP2startval.Value;
            params.SP2nTrials = app.SP2nTrials.Value;
            
            params.SP3cohvector = app.SP3cohvector.Value;
            params.SP3up = app.SP3up.Value;
            params.SP3down = app.SP3down.Value;
            params.SP3startval = app.SP3startval.Value;
            params.SP3nTrials = app.SP3nTrials.Value;
            
            
            % Global parameters
            params.nDots = app.NoDotsEditField.Value;
            params.dotSize = app.DotSizeEditField.Value;
            params.dotLifetime = app.DotLifetimeEditField.Value;
            params.trialDuration = app.TrialDurationEditField.Value;
            params.respFeedbackBool = app.respFeedbackBool.Value;
            params.pauseN = app.pausen.Value;
            
            %misc
            params.nAsave = app.nAsave.Value;
            app.pars = params;
            
            if app.loadResFlag == 0
                itrial = 1;
            elseif app.loadResFlag == 1
                notDoneIdx = find(isnan(app.allTrials(6,:)));
                itrial = notDoneIdx(1);
            end
                
            app.iTrialLabel.Text = string(itrial);
            app.nTrials = size(app.allTrials,2);
            app.nTrialsLabel.Text = string(app.nTrials);
            
            % wait for start signal from unity '9'
            while true
                startSig = fread(app.t,1)
                if startSig == 9
                    flushinput(app.t); % should work...
                    break
                end
            end
            pause(1)
            
            %% ============= Do the trials =============== %
            %%itrial = 1;
            %for itrial = 1:app.nTrials % do the initial trial set
            while itrial <= app.nTrials
                
                app.iTrialLabel.Text = string(itrial);
                
                trialType = app.allTrials(1,itrial);
                trialSubtype = app.allTrials(2,itrial);
                vel = app.allTrials(3,itrial);
                walkstate = app.allTrials(4,itrial);
                
                
                istate = find(walkstate==app.states);
                ivel = find(app.velocities==vel);
                
                if trialType == 1 % constant stimuli, pre-assigned coherence
                    coh = app.allTrials(5,itrial);
                else
                    idx = app.cond(ivel,istate).stair(trialType-1).dir(trialSubtype).stimValIdx; % index of coherence in that staircase
                    coh = app.cond(ivel,istate).stair(trialType-1).dir(trialSubtype).coherences(idx); % value of coherence for trial
                    if trialSubtype == 2 % if dir == 2, motion is backwards, so coh is -ve
                        coh = -coh;
                    end
                    app.allTrials(5,itrial) = coh;
                end
                
                if walkstate == 0
                    stateChar = char('s');
                elseif walkstate == 1
                    stateChar = char('w');
                end
                
                % create and send data char to Unity
                data = char(['0,0,' num2str(vel) ',' stateChar ',' num2str(coh)]); % signed coherence
                try
                    fwrite(app.t,data);
                catch
                    pause(0.1)
                    return
                end
                pause(0.3)
                trialResult = fread(app.t,1); % responses: 0 is forwards motion, 1 is backwards motion, 9 is skipped
                pause(0.05) % necessary??
                app.allTrials(6, itrial) = trialResult;
                
                if trialResult~=9 % if valid trial
                    
                    
                    % check correctness of result, update staircase.
                    if trialType ~= 1
                        if (coh > 0 && trialResult == 0) || (coh < 0 && trialResult == 1)
                            checkCorrect = 1
                        else
                            checkCorrect = 0
                        end
                        
                        app.cond(ivel,istate).stair(trialType-1).dir(trialSubtype).resp =...
                            [app.cond(ivel,istate).stair(trialType-1).dir(trialSubtype).resp, checkCorrect];
                        
                        
                        if checkCorrect == 0 % incorrect response,
                            % correct in a row to 0, incorrect in a row increment
                            app.cond(ivel,istate).stair(trialType-1).dir(trialSubtype).correctinarow = 0;
                            app.cond(ivel,istate).stair(trialType-1).dir(trialSubtype).incorrectinarow =...
                                app.cond(ivel,istate).stair(trialType-1).dir(trialSubtype).incorrectinarow + 1;
                            
                            % if meets DOWN criteria, make stimulus easier, set incorrect in a row to 0 again
                            if app.cond(ivel,istate).stair(trialType-1).dir(trialSubtype).incorrectinarow ==...
                                    app.cond(ivel,istate).stair(trialType-1).dir(trialSubtype).D
                                
                                app.cond(ivel,istate).stair(trialType-1).dir(trialSubtype).stimValIdx = ...
                                    app.cond(ivel,istate).stair(trialType-1).dir(trialSubtype).stimValIdx + 1;
                                
                                % if at easiest coherence, stay at easiest
                                if app.cond(ivel,istate).stair(trialType-1).dir(trialSubtype).stimValIdx >...
                                        numel(app.cond(ivel,istate).stair(trialType-1).dir(trialSubtype).coherences)
                                    app.cond(ivel,istate).stair(trialType-1).dir(trialSubtype).stimValIdx = ...
                                        numel(app.cond(ivel,istate).stair(trialType-1).dir(trialSubtype).coherences);
                                end
                                % set incorrect in a row to 0 for that staircase
                                app.cond(ivel,istate).stair(trialType-1).dir(trialSubtype).incorrectinarow = 0;
                            end
                        end
                        
                        if checkCorrect == 1 % correct response
                            % increment correct in a row for that staircase, set incorrect in a row to 0
                            app.cond(ivel,istate).stair(trialType-1).dir(trialSubtype).incorrectinarow = 0;
                            app.cond(ivel,istate).stair(trialType-1).dir(trialSubtype).correctinarow = ...
                                app.cond(ivel,istate).stair(trialType-1).dir(trialSubtype).correctinarow + 1;
                            
                            % if meets UP criteria, make stimulus harder, set correct in a row to 0 again
                            if app.cond(ivel,istate).stair(trialType-1).dir(trialSubtype).correctinarow ==...
                                    app.cond(ivel,istate).stair(trialType-1).dir(trialSubtype).U
                                
                                app.cond(ivel,istate).stair(trialType-1).dir(trialSubtype).stimValIdx = ...
                                    app.cond(ivel,istate).stair(trialType-1).dir(trialSubtype).stimValIdx + -1;
                                
                                % if at hardest coherence, stay at hardest
                                if app.cond(ivel,istate).stair(trialType-1).dir(trialSubtype).stimValIdx < 1
                                    app.cond(ivel,istate).stair(trialType-1).dir(trialSubtype).stimValIdx = 1;
                                end
                                app.cond(ivel,istate).stair(trialType-1).dir(trialSubtype).correctinarow = 0;
                            end
                        end


                    end
                else % if trial was skipped / invalid
                    
                    %app.allTrials = [app.allTrials, app.allTrials(:,itrial)];
                    %app.nTrials = app.nTrials + 1;
                    %app.nTrialsLabel.Text = string(app.nTrials);
                    
                    
                end
                
                
                % live plotting stuff
                %disp(trialResult)
                %if trialResult == 0
                %    pointcolor = [1 0 0];
                %elseif trialResult==1
                %    pointcolor = [0 0 1];
                %end
                %subplot(app.nConds, 1, velnum)
                %plot(velTrial, coh,'ko','markerfacecolor',pointcolor) % initial stimuli
                %pause(0.1)
                
                if rem(itrial,app.nAsave.Value) == 0
                    results = struct();
                    results.info.sub_sesh =  app.saveName.Value;
                    results.info.coherences = app.coherences;
                    results.info.velocities = app.velocities;
                    results.info.states = app.states;
                    results.cond = app.cond;
                    results.allTrials = app.allTrials;
                    results.params = app.pars;
                    savestring = [app.path '\' app.saveName.Value];
                    save(savestring, 'results')
                end
                
                itrial = itrial + 1;
            end


            % send end of experiment to Unity.
            try
                data = char('end');
                fwrite(app.t,data);
            catch
                pause(0.1)
            end
        end

        % Callback function
        function initialTrialsMethodflag(app, event)
            value = app.SamplingDropDown.Value;
            app.iniflag = find(contains(app.SamplingDropDown.Items, value));
            
            if app.iniflag == 1
                app.SP1nReps.Enable = 'on';
                app.nTrialsEditField.Enable = 'off';
            else
                app.SP1nReps.Enable = 'off';
            end
            
            if app.iniflag == 2 % staircase
                app.UpEditField.Enable = 'on';
                app.DownEditField.Enable = 'on';
                app.nTrialsEditField.Enable = 'on';
            else
                app.UpEditField.Enable = 'off';
                app.DownEditField.Enable = 'off';
            end
        end

        % Value changed function: SP1cohvector
        function checkValidIniCohs(app, event)
            initialCoherences = eval(app.SP1cohvector.Value);
            fullcoherences = eval(app.SpeedDiffVectorEditField.Value);
            check = intersect(initialCoherences, fullcoherences);
            if numel(check) ~= numel(initialCoherences)
                app.SP1invalidvals.Text = 'Invalid';
                app.ReadyButton.Enable = 'off';
            else
                app.SP1invalidvals.Text = ' ';
                app.ReadyButton.Enable = 'on';
            end
            
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            try
                fclose(app.t);
                delete(app.t);
            catch
            end
            clear app.t;
            app.ConnectButton.Enable = 'on';
            app.DisconnectButton.Enable = 'off';
            app.CloseButton.Enable = 'off';
            app.OpenButton.Enable = 'off';
            app.connectFlagLabel.Text = 'Disconnected';
            app.openFlagLabel.Text = 'Closed';
            app.SendButton.Enable = 'off';
            app.SendPathButton.Enable = 'off';
        end

        % Value changed function: SessionEditField, 
        % SubjectnameEditField
        function setSaveName(app, event)
            if ~isempty(app.SubjectnameEditField.Value) && ~isempty(app.SessionEditField.Value)
                app.saveName.Value = [app.SubjectnameEditField.Value '-' app.SessionEditField.Value];
            end
            
        end

        % Button pushed function: SaveResultsButton
        function saveResults(app, event)
            filter = {'*.mat'};
            [file, savepath] = uiputfile(filter);
            results = struct();
            results.info.sub_sesh =  app.saveName.Value;
            results.info.coherences = app.coherences;
            results.info.velocities = app.velocities;
            results.info.states = app.states;
            results.cond = app.cond;
            results.allTrials = app.allTrials;
            try
                save([savepath file], 'results')
            catch
            end
            
        end

        % Button pushed function: SaveParamsButton
        function saveParams(app, event)
            %fffo
            filter = {'*.mat'};
            [file, savepath] = uiputfile(filter);
            % put parameters into a struct here and save as a var.
            params = struct;
            % Stimulus and response space
            params.cohVec = app.SpeedDiffVectorEditField.Value;
            params.velVec = app.VelocitiesVectorEditField.Value;
            params.state = app.StateEditField.Value;
            params.responseSpace = app.ResponseSpaceEditField.Value;
            
            % Trial settings
            params.SP1cohvector = app.SP1cohvector.Value;
            params.SP1nreps = app.SP1nReps.Value;
            
            params.SP2cohvector = app.SP2cohvector.Value;
            params.SP2up = app.SP2up.Value;
            params.SP2down = app.SP2down.Value;
            params.SP2startval = app.SP2startval.Value;
            params.SP2nTrials = app.SP2nTrials.Value;
            
            params.SP3cohvector = app.SP3cohvector.Value;
            params.SP3up = app.SP3up.Value;
            params.SP3down = app.SP3down.Value;
            params.SP3startval = app.SP3startval.Value;
            params.SP3nTrials = app.SP3nTrials.Value;
            
            
            % Global parameters
            params.nDots = app.NoDotsEditField.Value;
            params.dotSize = app.DotSizeEditField.Value;
            params.dotLifetime = app.DotLifetimeEditField.Value;
            params.trialDuration = app.TrialDurationEditField.Value;
            params.respFeedbackBool = app.respFeedbackBool.Value;
            params.pauseN = app.pausen.Value;
            params.ISI = app.ISIEditField.Value;
            
            %misc
            params.nAsave = app.nAsave.Value;
            try
                save([savepath file], 'params')
            catch
            end
        end

        % Button pushed function: LoadParamsButton
        function loadParams(app, event)
            filter = {'*.mat'};
            [file, loadpath] = uigetfile(filter);
            try
                load([loadpath file], 'params');
                % distribute parameters here.
                % Stimulus and response space
                app.SpeedDiffVectorEditField.Value = params.cohVec;
                app.VelocitiesVectorEditField.Value = params.velVec;
                app.StateEditField.Value = params.state;
                app.ResponseSpaceEditField.Value = params.responseSpace;
                
                % Trial settings
                app.SP1cohvector.Value = params.SP1cohvector;
                app.SP1nReps.Value = params.SP1nreps;
                
                app.SP2cohvector.Value = params.SP2cohvector;
                app.SP2up.Value = params.SP2up;
                app.SP2down.Value = params.SP2down;
                app.SP2startval.Value = params.SP2startval;
                app.SP2nTrials.Value = params.SP2nTrials;
                
                app.SP3cohvector.Value = params.SP3cohvector;
                app.SP3up.Value = params.SP3up;
                app.SP3down.Value = params.SP3down;
                app.SP3startval.Value = params.SP3startval;
                app.SP3nTrials.Value = params.SP3nTrials;
                
                % Global parameters
                app.NoDotsEditField.Value = params.nDots;
                app.DotSizeEditField.Value = params.dotSize;
                app.DotLifetimeEditField.Value = params.dotLifetime;
                app.TrialDurationEditField.Value = params.trialDuration;
                app.respFeedbackBool.Value = params.respFeedbackBool;
                app.pausen.Value = params.pauseN;
                app.ISIEditField.Value = params.ISI;
                
                %misc
                app.nAsave.Value = params.nAsave;
            catch
            end
        end

        % Button pushed function: SetDirButton
        function setDirectory(app, event)
            
            app.path=uigetdir;                    % Calls 'uigetdir' to obtain the directory location from the user
            app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.UIFigure.Visible = 'on';
            try
                app.directoryName.Value = app.path;
            catch
                %b(ismember(b,{'.','..'})) = [];      % Removes unnecessary '.' and '..' results from the display.
            end
        end

        % Callback function
        function randSampleProb(app, event)
            value = app.RandSampleprobEditField.Value;
            app.randsamplep = value;
            
        end

        % Callback function
        function EnternRepsAdaptive(app, event)
            value = app.adaptnReps.Value;
            app.totalTrials = app.nIni + (value*app.nConds);
            app.TotalTrialsEditField.Value = app.totalTrials;
            app.nTrialsLabel.Text = string(app.totalTrials);
        end

        % Button pushed function: SendPathButton
        function sendPath2Unity(app, event)
            savestring = string(['spath,' app.path '\' app.saveName.Value]);
            fwrite(app.t, savestring);
            % report fail here.
            
        end

        % Button pushed function: LoadSessionButton
        function loadSession(app, event)
            % open results file
            filter = {'*.mat'};
            [file, loadpath] = uigetfile(filter);
            try
                load([loadpath file], 'results');
                app.SpeedDiffVectorEditField.Value = results.params.cohVec;
                app.VelocitiesVectorEditField.Value = results.params.velVec;
                app.StateEditField.Value = results.params.state;
                app.ResponseSpaceEditField.Value = results.params.responseSpace;
                
                % Trial settings
                app.SP1cohvector.Value = results.params.SP1cohvector;
                app.SP1nReps.Value = results.params.SP1nreps;
                
                app.SP2cohvector.Value = results.params.SP2cohvector;
                app.SP2up.Value = results.params.SP2up;
                app.SP2down.Value = results.params.SP2down;
                app.SP2startval.Value = results.params.SP2startval;
                app.SP2nTrials.Value = results.params.SP2nTrials;
                
                app.SP3cohvector.Value = results.params.SP3cohvector;
                app.SP3up.Value = results.params.SP3up;
                app.SP3down.Value = results.params.SP3down;
                app.SP3startval.Value = results.params.SP3startval;
                app.SP3nTrials.Value = results.params.SP3nTrials;
                
                % Global parameters
                app.NoDotsEditField.Value = results.params.nDots;
                app.DotSizeEditField.Value = results.params.dotSize;
                app.DotLifetimeEditField.Value = results.params.dotLifetime;
                app.TrialDurationEditField.Value = results.params.trialDuration;
                app.respFeedbackBool.Value = results.params.respFeedbackBool;
                app.pausen.Value = results.params.pauseN;
                
                %misc
                app.nAsave.Value = results.params.nAsave;
                
                % load trials array and staircases
                app.cond = results.cond;
                app.allTrials = results.allTrials;
                app.nTrials = length(app.allTrials);
                app.states = results.info.states;
                app.velocities = results.info.velocities;
                
                app.loadResFlag = 1;
                
            catch
            end
            % assign params, app.allTrials, app.cond and anything else
            % then click ready...
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.initialTrialsPanel = uipanel(app.MainTab);
            app.initialTrialsPanel.Title = 'Trial Generation';
            app.GenerateButton = uibutton(app.initialTrialsPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @generateTrials, true);
            app.GenerateButton.Enable = 'off';
            app.GenerateButton.Text = 'Generate';
            app.iTrialLabel = uilabel(app.initialTrialsPanel);
            app.iTrialLabel.Text = 'iTrial';
            app.Label = uilabel(app.initialTrialsPanel);
            app.Label.Text = '/';
            app.nTrialsLabel = uilabel(app.initialTrialsPanel);
            app.nTrialsLabel.Text = 'nTrials';
            app.SamplingProcedure1constantstimuliPanel = uipanel(app.initialTrialsPanel);
            app.SamplingProcedure1constantstimuliPanel.Title = 'Sampling Procedure #1 (constant stimuli)';
            app.nRepsEditFieldLabel = uilabel(app.SamplingProcedure1constantstimuliPanel);
            app.nRepsEditFieldLabel.Text = 'nReps';
            app.SP1nReps = uieditfield(app.SamplingProcedure1constantstimuliPanel, 'numeric');
            app.SP1nReps.Value = 20;
            app.SDsLabel = uilabel(app.SamplingProcedure1constantstimuliPanel);
            app.SDsLabel.Text = 'SDs';
            app.SP1cohvector = uieditfield(app.SamplingProcedure1constantstimuliPanel, 'text');
            app.SP1cohvector.ValueChangedFcn = createCallbackFcn(app, @checkValidIniCohs, true);
            app.SP1cohvector.Value = '[0.05 0.1 0.2 0.5 1 2.5 5 10 50]';
            app.conddirLabel_3 = uilabel(app.SamplingProcedure1constantstimuliPanel);
            app.conddirLabel_3.Text = '/cond/dir';
            app.TotaltrialsLabel_2 = uilabel(app.SamplingProcedure1constantstimuliPanel);
            app.TotaltrialsLabel_2.Text = 'Total trials';
            app.SP1totaltrials = uieditfield(app.SamplingProcedure1constantstimuliPanel, 'numeric');
            app.SP1totaltrials.Enable = 'off';
            app.SP1invalidvals = uilabel(app.SamplingProcedure1constantstimuliPanel);
            app.SP1invalidvals.Text = 'Warning msg';
            app.SamplingProcedure2staircase1Panel = uipanel(app.initialTrialsPanel);
            app.SamplingProcedure2staircase1Panel.Title = 'Sampling Procedure #2  (staircase 1)';
            app.SP2invalidvals = uilabel(app.SamplingProcedure2staircase1Panel);
            app.SP2invalidvals.Text = 'Warning msg';
            app.SDsLabel_2 = uilabel(app.SamplingProcedure2staircase1Panel);
            app.SDsLabel_2.Text = 'SDs';
            app.SP2cohvector = uieditfield(app.SamplingProcedure2staircase1Panel, 'text');
            app.SP2cohvector.Value = '[0.05 0.1 0.2 0.5 1 2.5 5 10 50]';
            app.UpEditField_2Label_2 = uilabel(app.SamplingProcedure2staircase1Panel);
            app.UpEditField_2Label_2.Text = 'Up';
            app.SP2up = uieditfield(app.SamplingProcedure2staircase1Panel, 'numeric');
            app.DownEditField_2Label = uilabel(app.SamplingProcedure2staircase1Panel);
            app.DownEditField_2Label.Text = 'Down';
            app.SP2down = uieditfield(app.SamplingProcedure2staircase1Panel, 'numeric');
            app.nTrialsEditField_2Label = uilabel(app.SamplingProcedure2staircase1Panel);
            app.nTrialsEditField_2Label.Text = 'nTrials';
            app.SP2nTrials = uieditfield(app.SamplingProcedure2staircase1Panel, 'numeric');
            app.startingvalueEditField_2Label = uilabel(app.SamplingProcedure2staircase1Panel);
            app.startingvalueEditField_2Label.Text = 'starting value';
            app.SP2startval = uieditfield(app.SamplingProcedure2staircase1Panel, 'numeric');
            app.conddirLabel = uilabel(app.SamplingProcedure2staircase1Panel);
            app.conddirLabel.Text = '/cond/dir';
            app.TotaltrialsLabel_3 = uilabel(app.SamplingProcedure2staircase1Panel);
            app.TotaltrialsLabel_3.Text = 'Total trials';
            app.SP2totaltrials = uieditfield(app.SamplingProcedure2staircase1Panel, 'numeric');
            app.SP2totaltrials.Enable = 'off';
            app.SamplingProcedure3staircase2Panel = uipanel(app.initialTrialsPanel);
            app.SamplingProcedure3staircase2Panel.Title = 'Sampling Procedure #3 (staircase 2)';
            app.SP3invalidvals = uilabel(app.SamplingProcedure3staircase2Panel);
            app.SP3invalidvals.Text = 'Warning msg';
            app.SDsLabel_3 = uilabel(app.SamplingProcedure3staircase2Panel);
            app.SDsLabel_3.Text = 'SDs';
            app.SP3cohvector = uieditfield(app.SamplingProcedure3staircase2Panel, 'text');
            app.SP3cohvector.Value = '[0.05 0.1 0.2 0.5 1 2.5 5 10 50]';
            app.UpEditField_4Label = uilabel(app.SamplingProcedure3staircase2Panel);
            app.UpEditField_4Label.Text = 'Up';
            app.SP3up = uieditfield(app.SamplingProcedure3staircase2Panel, 'numeric');
            app.DownEditField_3Label = uilabel(app.SamplingProcedure3staircase2Panel);
            app.DownEditField_3Label.Text = 'Down';
            app.SP3down = uieditfield(app.SamplingProcedure3staircase2Panel, 'numeric');
            app.nTrialsEditField_3Label = uilabel(app.SamplingProcedure3staircase2Panel);
            app.nTrialsEditField_3Label.Text = 'nTrials';
            app.SP3nTrials = uieditfield(app.SamplingProcedure3staircase2Panel, 'numeric');
            app.startingvalueEditField_3Label = uilabel(app.SamplingProcedure3staircase2Panel);
            app.startingvalueEditField_3Label.Text = 'starting value';
            app.SP3startval = uieditfield(app.SamplingProcedure3staircase2Panel, 'numeric');
            app.conddirLabel_2 = uilabel(app.SamplingProcedure3staircase2Panel);
            app.conddirLabel_2.Text = '/cond/dir';
            app.TotaltrialsLabel_4 = uilabel(app.SamplingProcedure3staircase2Panel);
            app.TotaltrialsLabel_4.Text = 'Total trials';
            app.SP3totaltrials = uieditfield(app.SamplingProcedure3staircase2Panel, 'numeric');
            app.SP3totaltrials.Enable = 'off';
            app.stimAndResponsePanel = uipanel(app.MainTab);
            app.stimAndResponsePanel.Title = 'Stimulus and Response Space';
            app.StimulusSpaceLabel = uilabel(app.stimAndResponsePanel);
            app.StimulusSpaceLabel.Text = 'Stimulus Space';
            app.SetButton = uibutton(app.stimAndResponsePanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @genStimSpace, true);
            app.SetButton.Text = 'Set';
            app.SpeedDiffVectorEditFieldLabel = uilabel(app.stimAndResponsePanel);
            app.SpeedDiffVectorEditFieldLabel.Text = {'Speed Diff'; 'Vector'};
            app.SpeedDiffVectorEditField = uieditfield(app.stimAndResponsePanel, 'text');
            app.SpeedDiffVectorEditField.Value = '[0.01 0.025 0.05 0.075 0.1 0.2 0.3 0.4 0.5 0.75 1 2.5 5 7.5 10 15 20 50 100]';
            app.VelocitiesVectorEditFieldLabel = uilabel(app.stimAndResponsePanel);
            app.VelocitiesVectorEditFieldLabel.Text = {'Velocities'; 'Vector'};
            app.VelocitiesVectorEditField = uieditfield(app.stimAndResponsePanel, 'text');
            app.VelocitiesVectorEditField.Value = '[25 50 100]';
            app.StateEditFieldLabel = uilabel(app.stimAndResponsePanel);
            app.StateEditFieldLabel.Text = 'State';
            app.StateEditField = uieditfield(app.stimAndResponsePanel, 'text');
            app.StateEditField.Value = '[0]';
            app.ResponseSpaceEditFieldLabel = uilabel(app.stimAndResponsePanel);
            app.ResponseSpaceEditFieldLabel.Text = {'Response'; 'Space'};
            app.ResponseSpaceEditField = uieditfield(app.stimAndResponsePanel, 'text');
            app.ResponseSpaceEditField.Value = '[0 1]';
            app.ReadyButton = uibutton(app.MainTab, 'push');
            app.ReadyButton.ButtonPushedFcn = createCallbackFcn(app, @startExperiment, true);
            app.ReadyButton.Text = 'Ready';
            app.TCPpanel = uipanel(app.MainTab);
            app.TCPpanel.Title = 'TCP connection';
            app.CloseButton = uibutton(app.TCPpanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @closeTCP, true);
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.TCPpanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @openTCP, true);
            app.OpenButton.Text = 'Open';
            app.ConnectButton = uibutton(app.TCPpanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.TCPpanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.IPEditFieldLabel = uilabel(app.TCPpanel);
            app.IPEditFieldLabel.Text = 'IP';
            app.IPfield = uieditfield(app.TCPpanel, 'text');
            app.IPfield.Value = '127.0.0.1';
            app.PortEditFieldLabel = uilabel(app.TCPpanel);
            app.PortEditFieldLabel.Text = 'Port';
            app.Portfield = uieditfield(app.TCPpanel, 'numeric');
            app.Portfield.ValueDisplayFormat = '%.0f';
            app.Portfield.Value = 10061;
            app.connectFlagLabel = uilabel(app.TCPpanel);
            app.connectFlagLabel.Text = 'Disconnected';
            app.openFlagLabel = uilabel(app.TCPpanel);
            app.openFlagLabel.Text = 'Closed';
            app.TCPerrormsgLabel = uilabel(app.TCPpanel);
            app.TCPerrormsgLabel.Text = 'TCPerrormsg';
            app.SaveLoadPanel = uipanel(app.MainTab);
            app.SaveLoadPanel.Title = 'Save/Load';
            app.SubjectnameEditFieldLabel = uilabel(app.SaveLoadPanel);
            app.SubjectnameEditFieldLabel.Text = {'Subject '; 'name:'};
            app.SubjectnameEditField = uieditfield(app.SaveLoadPanel, 'text');
            app.SubjectnameEditField.ValueChangedFcn = createCallbackFcn(app, @setSaveName, true);
            app.SessionEditFieldLabel = uilabel(app.SaveLoadPanel);
            app.SessionEditFieldLabel.Text = 'Session';
            app.SessionEditField = uieditfield(app.SaveLoadPanel, 'text');
            app.SessionEditField.ValueChangedFcn = createCallbackFcn(app, @setSaveName, true);
            app.SetDirButton = uibutton(app.SaveLoadPanel, 'push');
            app.SetDirButton.ButtonPushedFcn = createCallbackFcn(app, @setDirectory, true);
            app.SetDirButton.Text = 'Set Dir';
            app.directoryName = uieditfield(app.SaveLoadPanel, 'text');
            app.directoryName.Enable = 'off';
            app.saveName = uieditfield(app.SaveLoadPanel, 'text');
            app.saveName.Enable = 'off';
            app.SaveResultsButton = uibutton(app.SaveLoadPanel, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @saveResults, true);
            app.SaveResultsButton.Text = 'Save Results';
            app.AutosaveLabel = uilabel(app.SaveLoadPanel);
            app.AutosaveLabel.Text = 'Autosave';
            app.LoadParamsButton = uibutton(app.SaveLoadPanel, 'push');
            app.LoadParamsButton.ButtonPushedFcn = createCallbackFcn(app, @loadParams, true);
            app.LoadParamsButton.Text = 'Load Params';
            app.SaveParamsButton = uibutton(app.SaveLoadPanel, 'push');
            app.SaveParamsButton.ButtonPushedFcn = createCallbackFcn(app, @saveParams, true);
            app.SaveParamsButton.Text = 'Save Params';
            app.everyEditFieldLabel = uilabel(app.SaveLoadPanel);
            app.everyEditFieldLabel.Text = 'every';
            app.nAsave = uieditfield(app.SaveLoadPanel, 'numeric');
            app.SendPathButton = uibutton(app.SaveLoadPanel, 'push');
            app.SendPathButton.ButtonPushedFcn = createCallbackFcn(app, @sendPath2Unity, true);
            app.SendPathButton.Text = 'Send Path';
            app.GlobalParametersPanel = uipanel(app.MainTab);
            app.GlobalParametersPanel.Title = 'Global Parameters';
            app.respFeedbackBool = uicheckbox(app.GlobalParametersPanel);
            app.respFeedbackBool.Text = '';
            app.TrialDurationEditFieldLabel_2 = uilabel(app.GlobalParametersPanel);
            app.TrialDurationEditFieldLabel_2.Text = {'Response'; 'feedback'};
            app.PauseeveryLabel = uilabel(app.GlobalParametersPanel);
            app.PauseeveryLabel.Text = {'Pause'; 'every'};
            app.pausen = uieditfield(app.GlobalParametersPanel, 'numeric');
            app.pausen.Limits = [0 Inf];
            app.pausen.Value = 50;
            app.SendButton = uibutton(app.GlobalParametersPanel, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @setGlobalParams, true);
            app.SendButton.Text = 'Send';
            app.NoDotsLabel = uilabel(app.GlobalParametersPanel);
            app.NoDotsLabel.Text = 'No. Dots';
            app.NoDotsEditField = uieditfield(app.GlobalParametersPanel, 'numeric');
            app.NoDotsEditField.Limits = [0 Inf];
            app.NoDotsEditField.Value = 100;
            app.DotSizeEditFieldLabel = uilabel(app.GlobalParametersPanel);
            app.DotSizeEditFieldLabel.Text = 'Dot Size';
            app.DotSizeEditField = uieditfield(app.GlobalParametersPanel, 'numeric');
            app.DotSizeEditField.Limits = [0 Inf];
            app.DotSizeEditField.Value = 2;
            app.DotLifetimeEditFieldLabel = uilabel(app.GlobalParametersPanel);
            app.DotLifetimeEditFieldLabel.Text = {'Dot'; 'Lifetime'};
            app.DotLifetimeEditField = uieditfield(app.GlobalParametersPanel, 'numeric');
            app.DotLifetimeEditField.Limits = [0 Inf];
            app.DotLifetimeEditField.Value = 0.25;
            app.TrialDurationEditFieldLabel = uilabel(app.GlobalParametersPanel);
            app.TrialDurationEditFieldLabel.Text = {'Trial'; 'Duration'};
            app.TrialDurationEditField = uieditfield(app.GlobalParametersPanel, 'numeric');
            app.TrialDurationEditField.Limits = [0 Inf];
            app.TrialDurationEditField.Value = 1;
            app.ISIEditFieldLabel = uilabel(app.GlobalParametersPanel);
            app.ISIEditFieldLabel.Text = 'ISI';
            app.ISIEditField = uieditfield(app.GlobalParametersPanel, 'numeric');
            app.ISIEditField.Value = 0.2;
            app.LoadSessionButton = uibutton(app.MainTab, 'push');
            app.LoadSessionButton.ButtonPushedFcn = createCallbackFcn(app, @loadSession, true);
            app.LoadSessionButton.Text = 'Load Session';
            app.SpeedDifference2Label = uilabel(app.UIFigure);
            app.SpeedDifference2Label.Text = 'Speed Difference 2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SD2_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end